classdef RobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        ESTOPBUTTONLabel               matlab.ui.control.Label
        InitialiseSimulationButton     matlab.ui.control.Button
        LEGOSORTINGSYSTEMLabel         matlab.ui.control.Label
        StoppedLabel                   matlab.ui.control.Label
        StateLamp                      matlab.ui.control.Lamp
        OPTIONSPanel                   matlab.ui.container.Panel
        YellowCheckBox                 matlab.ui.control.CheckBox
        SortbycolourLabel              matlab.ui.control.Label
        RedCheckBox                    matlab.ui.control.CheckBox
        BlueCheckBox                   matlab.ui.control.CheckBox
        GreenCheckBox                  matlab.ui.control.CheckBox
        x4CheckBox                     matlab.ui.control.CheckBox
        SortbysizeLabel                matlab.ui.control.Label
        x2CheckBox                     matlab.ui.control.CheckBox
        x2CheckBox_2                   matlab.ui.control.CheckBox
        x1CheckBox                     matlab.ui.control.CheckBox
        PROPERTIESPanel                matlab.ui.container.Panel
        NoofbricksEditFieldLabel       matlab.ui.control.Label
        NoofbricksEditField            matlab.ui.control.NumericEditField
        InitialisePropertiesButton     matlab.ui.control.Button
        MOVEBRICKSButton               matlab.ui.control.Button
        EStop_Button                   matlab.ui.control.Button
        TeachTab                       matlab.ui.container.Tab
        JointAnglesPanel               matlab.ui.container.Panel
        EditField_1                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        Joint1135135SliderLabel        matlab.ui.control.Label
        Joint1135135Slider             matlab.ui.control.Slider
        Joint2085SliderLabel           matlab.ui.control.Label
        Joint2085Slider                matlab.ui.control.Slider
        Joint31095SliderLabel          matlab.ui.control.Label
        Joint31095Slider               matlab.ui.control.Slider
        Joint49090SliderLabel          matlab.ui.control.Label
        Joint49090Slider               matlab.ui.control.Slider
        Joint58585SliderLabel          matlab.ui.control.Label
        Joint58585Slider               matlab.ui.control.Slider
        EditField_5                    matlab.ui.control.NumericEditField
        EndEffectorPanel               matlab.ui.container.Panel
        XmmLabel                       matlab.ui.control.Label
        XmmEditField                   matlab.ui.control.NumericEditField
        YmmEditFieldLabel              matlab.ui.control.Label
        YmmEditField                   matlab.ui.control.NumericEditField
        ZmmEditFieldLabel              matlab.ui.control.Label
        ZmmEditField                   matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        EditField_7Label               matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        RunButton_2                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        JogTab                         matlab.ui.container.Tab
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        JointVelocityControlPanel      matlab.ui.container.Panel
        Joint1SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint5SliderLabel              matlab.ui.control.Label
        Joint5Slider                   matlab.ui.control.Slider
        Joint3SliderLabel              matlab.ui.control.Label
        Joint3Slider                   matlab.ui.control.Slider
        Joint4SliderLabel              matlab.ui.control.Label
        Joint4Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        CartesianVelocityControlPanel  matlab.ui.container.Panel
        XSliderLabel                   matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        ZSlider                        matlab.ui.control.Slider
        RSliderLabel                   matlab.ui.control.Label
        RSlider                        matlab.ui.control.Slider
        PSliderLabel                   matlab.ui.control.Label
        PSlider                        matlab.ui.control.Slider
        YaSliderLabel                  matlab.ui.control.Label
        YaSlider                       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        
        JogXSliderValue = 0;
        JogYSliderValue = 0;
        JogZSliderValue = 0;
        JogRSliderValue = 0;
        JogPSliderValue = 0;
        JogYaSliderValue = 0;
        
        jogJoint1Value = 0;
        jogJoint2Value = 0;
        jogJoint3Value = 0;
        jogJoint4Value = 0;
        jogJoint5Value = 0;
        jogJoint6Value = 0;
        
        sim;
        initialised = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, event)
            app.sim = Simulation();
        end

        % Button pushed function: InitialiseSimulationButton
        function InitialiseSimulationButtonPushed(app, event)
            app.StoppedLabel.Text = 'Initialising Simulation';
            app.NoofbricksEditField.Value = app.sim.brickNo;
            app.initialised = true;
            app.sim.init();
            app.StoppedLabel.Text = 'System Ready';
            app.StateLamp.Color = [0.07,0.62,1.00];
            app.MOVEBRICKSButton.Enable = true;
            app.ESTOPBUTTONLabel.Enable = true;
        end

        % Value changed function: Joint1135135Slider
        function Joint1135135SliderValueChanged(app, event)
            value = app.Joint1135135Slider.Value;
            app.EditField_1.Value = value;
        end

        % Value changed function: Joint2085Slider
        function Joint2085SliderValueChanged(app, event)
            value = app.Joint2085Slider.Value;
            app.EditField_2.Value = value;            
        end

        % Value changed function: Joint31095Slider
        function Joint31095SliderValueChanged(app, event)
            value = app.Joint31095Slider.Value;
            app.EditField_3.Value = value;           
        end

        % Value changed function: Joint49090Slider
        function Joint49090SliderValueChanged(app, event)
            value = app.Joint49090Slider.Value;
            app.EditField_4.Value = value;            
        end

        % Value changed function: Joint58585Slider
        function Joint58585SliderValueChanged(app, event)
            value = app.Joint58585Slider.Value;
            app.EditField_5.Value = value;        
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Q = [app.EditField_1.Value app.EditField_2.Value app.EditField_3.Value app.EditField_4.Value app.EditField_5.Value];
            Qdeg2rad = deg2rad(Q);
            app.sim.robot.moveJoints(Qdeg2rad);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            value = app.EditField_1.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            x = app.XmmEditField.Value;
            y = app.YmmEditField.Value;
            z = app.ZmmEditField.Value;
            app.sim.robot.moveEndEffector(x,y,z);
        end

        % Button pushed function: InitialisePropertiesButton
        function InitialisePropertiesButtonPushed(app, event)
            Value = app.NoofbricksEditField.Value;
            if (Value >= 1 && Value <= 10)
                app.sim.brickNo = app.NoofbricksEditField.Value;
                app.sim.getBrickNo();
                app.StoppedLabel.Text = 'Properties Initialised'
                app.StateLamp.Color = [1.00,0.41,0.16];
            else
                disp("Invalid number of bricks, pick a number between 1 and 10")
                disp(app.sim.brickNo);
            end
        end

        % Callback function
        function JogJoint1SliderValueChanging(app, event)
            app.JogJoint1Slider.Value = event.Value;
        end

        % Callback function
        function JogJoint2SliderValueChanging(app, event)
            app.JogJoint2Slider.Value = event.Value;
        end

        % Callback function
        function JogJoint3SliderValueChanging(app, event)
            app.JogJoint3Slider.Value = event.Value;
        end

        % Callback function
        function JogJoint4SliderValueChanging(app, event)
            app.JogJoint4Slider.Value = event.Value;
        end

        % Callback function
        function JogJoint5SliderValueChanging(app, event)
            app.JogJoint5Slider.Value = event.Value;
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            app.JogXSliderValue = event.Value;
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            app.JogYSliderValue = event.Value;
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            app.JogZSliderValue = event.Value;
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            app.JogRSliderValue = event.Value; 
        end

        % Value changing function: PSlider
        function PSliderValueChanging(app, event)
            app.JogPSliderValue = event.Value;
        end

        % Value changing function: YaSlider
        function YaSliderValueChanging(app, event)
            app.JogYaSliderValue= event.Value;
        end

        % Callback function
        function JogJoint1SliderValueChanged(app, event)
            app.JogJoint1Slider.Value = 0;
            app.jogJoint1Value = 0;
        end

        % Callback function
        function JogJoint2SliderValueChanged(app, event)
            app.JogJoint2Slider.Value = 0;
            app.jogJoint2Value = 0;
        end

        % Callback function
        function JogJoint3SliderValueChanged(app, event)
            app.JogJoint3Slider.Value = 0;
            app.jogJoint3Value = 0;
        end

        % Callback function
        function JogJoint4SliderValueChanged(app, event)
            app.JogJoint4Slider.Value = 0;
            app.jogJoint4Value = 0;
        end

        % Callback function
        function JogJoint5SliderValueChanged(app, event)
            app.JogJoint5Slider.Value = 0;
            app.jogJoint5Value = 0;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            app.XSlider.Value = 0;
            app.JogXSliderValue = 0;
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            app.YSlider.Value = 0;
            app.JogYSliderValue = 0;
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            app.ZSlider.Value = 0;
            app.JogZSliderValue = 0;
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            app.RSlider.Value = 0;
            app.JogRSliderValue = 0;
        end

        % Value changed function: PSlider
        function PSliderValueChanged(app, event)
            app.PSlider.Value = 0;
            app.JogPSliderValue = 0;
        end

        % Value changed function: YaSlider
        function YaSliderValueChanged(app, event)
            app.YaSlider.Value = 0;
            app.JogYaSliderValue = 0;
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            app.Joint1Slider.Value = 0;
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Callback function
        function EStop_ButtonValueChanged(app, event)
            value = app.EStop_Button.Value;
                if value
                    app.sim.estopPress();
                end
        end

        % Button pushed function: MOVEBRICKSButton
        function MOVEBRICKSButtonPushed(app, event)
            app.StoppedLabel.Text = 'Moving Bricks';
            app.StateLamp.Color = [0.00,1.00,0.00];
            app.sim.moveBricks();
            app.StoppedLabel.Text = 'Finished';
        end

        % Button pushed function: EStop_Button
        function EStop_ButtonPushed2(app, event)
            if app.sim.estop
                app.sim.estop = false;
                disp("ESTOP PRESSED, HALTING ALL PROCEDURES")
                app.StoppedLabel.Text = 'Ready'
                app.StateLamp.Color = [0.00,1.00,0.00]
            else 
                app.sim.estop = true;
                disp("ESTOP PRESSED, PRESS MOVING BRICKS TO CONTINUE")
                app.StoppedLabel.Text = 'E-Stop Pressed'
                app.StateLamp.Color = [1.00,0.00,0.00]
            end
        end

        % Value changing function: Joint1135135Slider
        function Joint1135135SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.ESTOPBUTTONLabel = uilabel(app.SimulationTab);
            app.ESTOPBUTTONLabel.Text = 'ESTOP BUTTON';
            app.InitialiseSimulationButton = uibutton(app.SimulationTab, 'push');
            app.InitialiseSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseSimulationButtonPushed, true);
            app.InitialiseSimulationButton.Text = 'Initialise Simulation';
            app.LEGOSORTINGSYSTEMLabel = uilabel(app.SimulationTab);
            app.LEGOSORTINGSYSTEMLabel.Text = 'LEGO SORTING SYSTEM';
            app.StoppedLabel = uilabel(app.SimulationTab);
            app.StoppedLabel.Text = 'Stopped';
            app.StateLamp = uilamp(app.SimulationTab);
            app.OPTIONSPanel = uipanel(app.SimulationTab);
            app.OPTIONSPanel.Title = 'OPTIONS';
            app.YellowCheckBox = uicheckbox(app.OPTIONSPanel);
            app.YellowCheckBox.Text = 'Yellow';
            app.SortbycolourLabel = uilabel(app.OPTIONSPanel);
            app.SortbycolourLabel.Text = 'Sort by colour: ';
            app.RedCheckBox = uicheckbox(app.OPTIONSPanel);
            app.RedCheckBox.Text = 'Red';
            app.BlueCheckBox = uicheckbox(app.OPTIONSPanel);
            app.BlueCheckBox.Text = 'Blue';
            app.GreenCheckBox = uicheckbox(app.OPTIONSPanel);
            app.GreenCheckBox.Text = 'Green';
            app.x4CheckBox = uicheckbox(app.OPTIONSPanel);
            app.x4CheckBox.Text = '2x4';
            app.SortbysizeLabel = uilabel(app.OPTIONSPanel);
            app.SortbysizeLabel.Text = 'Sort by size: ';
            app.x2CheckBox = uicheckbox(app.OPTIONSPanel);
            app.x2CheckBox.Text = '2x2';
            app.x2CheckBox_2 = uicheckbox(app.OPTIONSPanel);
            app.x2CheckBox_2.Text = '1x2';
            app.x1CheckBox = uicheckbox(app.OPTIONSPanel);
            app.x1CheckBox.Text = '1x1';
            app.PROPERTIESPanel = uipanel(app.SimulationTab);
            app.PROPERTIESPanel.Title = 'PROPERTIES';
            app.NoofbricksEditFieldLabel = uilabel(app.PROPERTIESPanel);
            app.NoofbricksEditFieldLabel.Text = 'No. of bricks';
            app.NoofbricksEditField = uieditfield(app.PROPERTIESPanel, 'numeric');
            app.NoofbricksEditField.Limits = [1 10];
            app.NoofbricksEditField.Value = 10;
            app.InitialisePropertiesButton = uibutton(app.SimulationTab, 'push');
            app.InitialisePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @InitialisePropertiesButtonPushed, true);
            app.InitialisePropertiesButton.Text = 'Initialise Properties';
            app.MOVEBRICKSButton = uibutton(app.SimulationTab, 'push');
            app.MOVEBRICKSButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEBRICKSButtonPushed, true);
            app.MOVEBRICKSButton.Enable = 'off';
            app.MOVEBRICKSButton.Text = 'MOVE BRICKS';
            app.EStop_Button = uibutton(app.SimulationTab, 'push');
            app.EStop_Button.ButtonPushedFcn = createCallbackFcn(app, @EStop_ButtonPushed2, true);
            app.EStop_Button.Icon = 'estop.jpeg';
            app.EStop_Button.Text = '';
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            app.JointAnglesPanel = uipanel(app.TeachTab);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.EditField_1 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_1.Limits = [-135 135];
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_2 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_2.Limits = [0 85];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_3.Limits = [-10 95];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_4.Limits = [-90 90];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Joint1135135SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint1135135SliderLabel.Text = {'Joint 1'; '(-135-135)'; ''};
            app.Joint1135135Slider = uislider(app.JointAnglesPanel);
            app.Joint1135135Slider.Limits = [-135 135];
            app.Joint1135135Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1135135SliderValueChanged, true);
            app.Joint1135135Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1135135SliderValueChanging, true);
            app.Joint2085SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint2085SliderLabel.Text = {'Joint 2'; '(0-85)'; ''};
            app.Joint2085Slider = uislider(app.JointAnglesPanel);
            app.Joint2085Slider.Limits = [0 85];
            app.Joint2085Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2085SliderValueChanged, true);
            app.Joint31095SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint31095SliderLabel.Text = {'Joint 3'; '(-10-95)'};
            app.Joint31095Slider = uislider(app.JointAnglesPanel);
            app.Joint31095Slider.Limits = [-10 95];
            app.Joint31095Slider.ValueChangedFcn = createCallbackFcn(app, @Joint31095SliderValueChanged, true);
            app.Joint49090SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint49090SliderLabel.Text = {'Joint 4'; '(-90-90)'; ''};
            app.Joint49090Slider = uislider(app.JointAnglesPanel);
            app.Joint49090Slider.Limits = [-90 90];
            app.Joint49090Slider.ValueChangedFcn = createCallbackFcn(app, @Joint49090SliderValueChanged, true);
            app.Joint58585SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint58585SliderLabel.Text = {'Joint 5'; '(-85-85)'; ''};
            app.Joint58585Slider = uislider(app.JointAnglesPanel);
            app.Joint58585Slider.Limits = [-85 85];
            app.Joint58585Slider.ValueChangedFcn = createCallbackFcn(app, @Joint58585SliderValueChanged, true);
            app.EditField_5 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_5.Limits = [-85 85];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EndEffectorPanel = uipanel(app.TeachTab);
            app.EndEffectorPanel.Title = 'End-Effector';
            app.XmmLabel = uilabel(app.EndEffectorPanel);
            app.XmmLabel.Text = {'X (mm)'; ''};
            app.XmmEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.XmmEditField.Limits = [-1000 1000];
            app.YmmEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.YmmEditFieldLabel.Text = {'Y (mm)'; ''};
            app.YmmEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.YmmEditField.Limits = [-1000 1000];
            app.ZmmEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.ZmmEditFieldLabel.Text = {'Z (mm)'; ''};
            app.ZmmEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.ZmmEditField.Limits = [-1000 1000];
            app.EditField_5Label = uilabel(app.EndEffectorPanel);
            app.EditField_5Label.Enable = 'off';
            app.EditField_5Label.Text = 'φ';
            app.REditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.REditField.Editable = 'off';
            app.REditField.Enable = 'off';
            app.EditField_6Label = uilabel(app.EndEffectorPanel);
            app.EditField_6Label.Enable = 'off';
            app.EditField_6Label.Text = 'θ';
            app.PEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.PEditField.Editable = 'off';
            app.PEditField.Enable = 'off';
            app.EditField_7Label = uilabel(app.EndEffectorPanel);
            app.EditField_7Label.Enable = 'off';
            app.EditField_7Label.Text = 'ψ';
            app.YawEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.YawEditField.Editable = 'off';
            app.YawEditField.Enable = 'off';
            app.RunButton_2 = uibutton(app.TeachTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.RunButton = uibutton(app.TeachTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.JogTab = uitab(app.TabGroup);
            app.JogTab.Title = 'Jog';
            app.DropDownLabel = uilabel(app.JogTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.JogTab);
            app.DropDown.Items = {'Slider', 'Controller', ''};
            app.DropDown.Value = 'Slider';
            app.JointVelocityControlPanel = uipanel(app.JogTab);
            app.JointVelocityControlPanel.Title = 'Joint Velocity Control';
            app.Joint1SliderLabel = uilabel(app.JointVelocityControlPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JointVelocityControlPanel);
            app.Joint1Slider.Limits = [-10 10];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.JointVelocityControlPanel);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.JointVelocityControlPanel);
            app.Joint5Slider.Limits = [-10 10];
            app.Joint3SliderLabel = uilabel(app.JointVelocityControlPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JointVelocityControlPanel);
            app.Joint3Slider.Limits = [-10 10];
            app.Joint4SliderLabel = uilabel(app.JointVelocityControlPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.JointVelocityControlPanel);
            app.Joint4Slider.Limits = [-10 10];
            app.Joint2SliderLabel = uilabel(app.JointVelocityControlPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JointVelocityControlPanel);
            app.Joint2Slider.Limits = [-10 10];
            app.CartesianVelocityControlPanel = uipanel(app.JogTab);
            app.CartesianVelocityControlPanel.Title = 'Cartesian Velocity Control';
            app.XSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartesianVelocityControlPanel);
            app.XSlider.Limits = [-10 10];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartesianVelocityControlPanel);
            app.YSlider.Limits = [-10 10];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartesianVelocityControlPanel);
            app.ZSlider.Limits = [-10 10];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.RSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.CartesianVelocityControlPanel);
            app.RSlider.Limits = [-10 10];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.PSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.PSliderLabel.Text = 'P';
            app.PSlider = uislider(app.CartesianVelocityControlPanel);
            app.PSlider.Limits = [-10 10];
            app.PSlider.ValueChangedFcn = createCallbackFcn(app, @PSliderValueChanged, true);
            app.PSlider.ValueChangingFcn = createCallbackFcn(app, @PSliderValueChanging, true);
            app.YaSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.YaSliderLabel.Text = 'Ya';
            app.YaSlider = uislider(app.CartesianVelocityControlPanel);
            app.YaSlider.Limits = [-10 10];
            app.YaSlider.ValueChangedFcn = createCallbackFcn(app, @YaSliderValueChanged, true);
            app.YaSlider.ValueChangingFcn = createCallbackFcn(app, @YaSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end